classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Slider                 matlab.ui.control.Slider
        UploadfromfileButton   matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        Label                  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        BackgroundButtonGroup  matlab.ui.container.ButtonGroup
        BillboardButton        matlab.ui.control.RadioButton
        MagazineButton         matlab.ui.control.RadioButton
        BeachButton            matlab.ui.control.RadioButton
        StyleButtonGroup       matlab.ui.container.ButtonGroup
        NoneButton             matlab.ui.control.RadioButton
        CanvasButton           matlab.ui.control.RadioButton
        StaryNightButton       matlab.ui.control.RadioButton
        SketchButton           matlab.ui.control.RadioButton
        PencilDrawingButton    matlab.ui.control.RadioButton
        UIAxes_2               matlab.ui.control.UIAxes
        LightHueLabel          matlab.ui.control.Label
        DisplayResultsButton   matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function results = display_results(app)
            global bg_type;
            global h_value;
            global style_img;
            global final_out;
            if bg_type == 1
                app.Slider.Visible = 'on';
                app.LightHueLabel.Visible = 'on';
            else
                app.Slider.Visible = 'off';
                app.LightHueLabel.Visible = 'off';
            end
            imagesc(app.UIAxes,style_img);
            switch bg_type
                case 1
                    final_out = billboard(style_img,h_value);
                case 2
                    final_out = magazine(style_img);
                case 3
                    final_out = beach(style_img);
            end


            imagesc(app.UIAxes_2,final_out);
        end
        
        function results = generate_style_img(app)
            global img;
            global style_type;
            global style_img;
            switch style_type
                case 0
                    img_out = img;
                case 1
                    img_out = canvas(img);
                case 2
                    img_out = stary(img);
                case 3
                    [img_out,~] = sketch(img);
                    img_out = repmat(img_out,[1,1,3]);
                case 4
                    [~,img_out] = sketch(img);
                
            end
            style_img = img_out;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'off');
            axis(app.UIAxes_2,'off');
            global img;
            global style_type;
            global bg_type;
            global h_value;
            style_type = 0;
            bg_type = 1;
            h_value = 0.5;
            img = imread('dataset\input\cat.jpg');
            generate_style_img(app);
            display_results(app);
        end

        % Button pushed function: UploadfromfileButton
        function UploadfromfileButtonPushed(app, event)
            [filename, pathname] = uigetfile( {'*.*';'*.bmp';'*.jpg';'*.tif';'*.jpg'}, '选择图像');
            global img;
            if isequal(filename,0) || isequal(pathname,0)
                errordlg('No file has been uploaded!');
                return;
            else
                image_root = [pathname,filename];
                img = imread(image_root);
            end
            generate_style_img(app);
            display_results(app);
            
        end

        % Selection changed function: BackgroundButtonGroup
        function BackgroundButtonGroupSelectionChanged(app, event)
            selectedButton = app.BackgroundButtonGroup.SelectedObject;
            global bg_type;
            switch selectedButton
                case app.BillboardButton
                    bg_type = 1;
                case app.MagazineButton
                    bg_type = 2;
                case app.BeachButton
                    bg_type = 3;
            end
            display_results(app);
            
        end

        % Selection changed function: StyleButtonGroup
        function StyleButtonGroupSelectionChanged(app, event)
            selectedButton = app.StyleButtonGroup.SelectedObject;
            global style_type;
            switch selectedButton
                case app.NoneButton
                    style_type = 0;
                case app.CanvasButton
                    style_type = 1;
                case app.StaryNightButton
                    style_type = 2;
                case app.SketchButton
                    style_type = 3;
                case app.PencilDrawingButton
                    style_type = 4;
                case app.PaintingButton
                    style_type = 5;
            end
            generate_style_img(app);
            display_results(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            global h_value;
            h_value = double(value);
            display_results(app);
        end

        % Button pushed function: DisplayResultsButton
        function DisplayResultsButtonPushed(app, event)
            global style_img;
            global final_out;
            figure;imshow(style_img);title('Style Changed');
            figure;imshow(final_out);title('Final Result');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            app.UploadfromfileButton = uibutton(app.UIFigure, 'push');
            app.UploadfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadfromfileButtonPushed, true);
            app.UploadfromfileButton.Text = 'Upload from file';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Style Change')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '数字图像处理  综合作业3';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '自84 2018011716 佴瑞乾';
            app.BackgroundButtonGroup = uibuttongroup(app.UIFigure);
            app.BackgroundButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BackgroundButtonGroupSelectionChanged, true);
            app.BackgroundButtonGroup.Title = 'Background';
            app.BillboardButton = uiradiobutton(app.BackgroundButtonGroup);
            app.BillboardButton.Text = 'Billboard';
            app.BillboardButton.Value = true;
            app.MagazineButton = uiradiobutton(app.BackgroundButtonGroup);
            app.MagazineButton.Text = 'Magazine';
            app.BeachButton = uiradiobutton(app.BackgroundButtonGroup);
            app.BeachButton.Text = 'Beach';
            app.StyleButtonGroup = uibuttongroup(app.UIFigure);
            app.StyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StyleButtonGroupSelectionChanged, true);
            app.StyleButtonGroup.Title = 'Style';
            app.NoneButton = uiradiobutton(app.StyleButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.CanvasButton = uiradiobutton(app.StyleButtonGroup);
            app.CanvasButton.Text = 'Canvas';
            app.StaryNightButton = uiradiobutton(app.StyleButtonGroup);
            app.StaryNightButton.Text = 'Stary Night';
            app.SketchButton = uiradiobutton(app.StyleButtonGroup);
            app.SketchButton.Text = 'Sketch';
            app.PencilDrawingButton = uiradiobutton(app.StyleButtonGroup);
            app.PencilDrawingButton.Text = 'Pencil Drawing';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Results')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.LightHueLabel = uilabel(app.UIFigure);
            app.LightHueLabel.Text = 'Light Hue';
            app.DisplayResultsButton = uibutton(app.UIFigure, 'push');
            app.DisplayResultsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayResultsButtonPushed, true);
            app.DisplayResultsButton.Text = 'Display Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end